classdef tutorialApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        UIAxes                    matlab.ui.control.UIAxes
        AmplitudeSliderLabel      matlab.ui.control.Label
        AmplitudeSlider           matlab.ui.control.Slider
        IntTestEditFieldLabel     matlab.ui.control.Label
        IntTestEditField          matlab.ui.control.NumericEditField
        StringTestEditFieldLabel  matlab.ui.control.Label
        StringTestEditField       matlab.ui.control.EditField
    end


    properties (Access = private)
        updateTimer % Timer to send OSC Updates
        OSCInterface % OSC Interface Container
        UPDATE_PERIOD = 0.1; % Update period in seconds
        IP_ADDR = '10.0.1.25'; % Destination IP Address
        PORT = 55830; % Transmit Port
        ADDRESS_BASE = '/MLABData/'; % Base Address for Data Sending
    end

    methods (Access = private)
    
        function sliderFunc(app, value)
            plot(app.UIAxes, value*peaks)
            app.UIAxes.YLim = [-1000 1000];
        end
        
        function oscTransmit(app)
            %add code for transmit here
            app.OSCInterface.sendDouble(strcat(app.ADDRESS_BASE, 'TestDouble1'), app.AmplitudeSlider.Value);
            app.OSCInterface.sendInt(strcat(app.ADDRESS_BASE, 'TestInt1'), app.IntTestEditField.Value);
            app.OSCInterface.sendString(strcat(app.ADDRESS_BASE, 'TestString1'), app.StringTestEditField.Value);
            disp("Test");
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %Add the jar file to the classpath using 'edit classpath.txt'
            import com.illposed.osc.*;
            import java.lang.String;
            app.OSCInterface = MatlabOSCInterface();
            app.OSCInterface.registerSender(app.IP_ADDR, app.PORT);
            
            app.updateTimer = timer('Period', app.UPDATE_PERIOD, 'ExecutionMode','fixedRate');
            app.updateTimer.TimerFcn = @(~,~) app.oscTransmit;
            start(app.updateTimer);
        end

        % Value changed function: AmplitudeSlider
        function AmplitudeSliderValueChanged(app, event)
            value = app.AmplitudeSlider.Value;
            sliderFunc(app, value);
        end

        % Value changing function: AmplitudeSlider
        function AmplitudeSliderValueChanging(app, event)
            changingValue = event.Value;
            sliderFunc(app, changingValue);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            stop(app.updateTimer);
            % app.OSCInterface.close();
            delete(app)          
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.AmplitudeSliderLabel = uilabel(app.UIFigure);
            app.AmplitudeSliderLabel.Text = 'Amplitude';
            app.AmplitudeSlider = uislider(app.UIFigure);
            app.AmplitudeSlider.ValueChangedFcn = createCallbackFcn(app, @AmplitudeSliderValueChanged, true);
            app.AmplitudeSlider.ValueChangingFcn = createCallbackFcn(app, @AmplitudeSliderValueChanging, true);
            app.IntTestEditFieldLabel = uilabel(app.UIFigure);
            app.IntTestEditFieldLabel.Text = 'Int Test';
            app.IntTestEditField = uieditfield(app.UIFigure, 'numeric');
            app.IntTestEditField.Limits = [-1000 5000];
            app.IntTestEditField.ValueDisplayFormat = '%.0f';
            app.StringTestEditFieldLabel = uilabel(app.UIFigure);
            app.StringTestEditFieldLabel.Text = 'String Test';
            app.StringTestEditField = uieditfield(app.UIFigure, 'text');
            app.StringTestEditField.Value = 'Test';
        end
    end

    methods (Access = public)

        % Construct app
        function app = tutorialApp

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end